classdef CalciumProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadImagesROITab                matlab.ui.container.Tab
        BrightnessSlider                matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        Yearlast2EditField              matlab.ui.control.EditField
        Yearlast2EditFieldLabel         matlab.ui.control.Label
        CutsSeparatedbyspacesEditField  matlab.ui.control.EditField
        CutsSeparatedbyspacesEditField_2Label  matlab.ui.control.Label
        PlateauRangeAfterPeaksEditField  matlab.ui.control.EditField
        PlateauRangeAfterPeaksEditFieldLabel  matlab.ui.control.Label
        GetintensitiesButton            matlab.ui.control.Button
        PopupandHalflifeButton          matlab.ui.control.Button
        TOMCheckBox                     matlab.ui.control.CheckBox
        AddCellButton                   matlab.ui.control.Button
        right                           matlab.ui.control.Button
        left                            matlab.ui.control.Button
        LoadFileButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ImageSlider                     matlab.ui.control.Slider
        ImageLabel                      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ExtractPeakParametersTab        matlab.ui.container.Tab
        AcquisitionEditField            matlab.ui.control.EditField
        AcquisitionEditFieldLabel       matlab.ui.control.Label
        MagnificatDropDown              matlab.ui.control.DropDown
        MagnificatDropDownLabel         matlab.ui.control.Label
        ChambDropDown                   matlab.ui.control.DropDown
        ChambDropDownLabel              matlab.ui.control.Label
        DishEditField                   matlab.ui.control.EditField
        DishEditFieldLabel              matlab.ui.control.Label
        TrialEditField                  matlab.ui.control.EditField
        TrialEditFieldLabel             matlab.ui.control.Label
        BitDepthDropDown                matlab.ui.control.DropDown
        BitDepthDropDownLabel           matlab.ui.control.Label
        FramerateEditField              matlab.ui.control.EditField
        FramerateEditFieldLabel         matlab.ui.control.Label
        GenotyDropDown                  matlab.ui.control.DropDown
        GenotyDropDownLabel             matlab.ui.control.Label
        DaysinVitroEditField            matlab.ui.control.EditField
        DaysinVitroEditFieldLabel       matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        AllCheckBox                     matlab.ui.control.CheckBox
        NeuronsEditField                matlab.ui.control.EditField
        NeuronsEditFieldLabel           matlab.ui.control.Label
        ProminenceEditField             matlab.ui.control.EditField
        ProminenceEditFieldLabel        matlab.ui.control.Label
        MinHeightEditField              matlab.ui.control.EditField
        ListBox                         matlab.ui.control.ListBox
        ChooseDirectoryButton           matlab.ui.control.Button
        MinHeightEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        myFolder    
        filePattern
        tifFiles   
        play
        roi
        means
        numImages
        cuts
        
        caDirectory
        caFiles
    end
    
    methods (Access = private)
        
        function displayImage(app, index)
            set(app.ImageSlider, 'value', index)
            
            file = app.tifFiles(round(index)); % why is there a round y?
            filePath = fullfile(file.folder, file.name);
            % baseFileName = file.name; 
            % fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);
            imageArray = imread(filePath);
            app.Label.Text = file.name;
            app.ImageLabel.Text = round(index)+ "/" +app.numImages;
            
            global imageshown
            imageshown = imageArray;
            
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imadjust(imageArray), 'Parent', app.UIAxes, ... % dunno what this does, oh well
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end
        
        function showroi(app)
            for i = 1:length(app.roi)
                app.roi{i}.Visible = 1;
            end
            drawnow;
        end
        
        function showGraph(app)
            
            Array=csvread(outputFolder);
            % get time in here as the x axis
            photopath = app.myFolder +"\GFP";
            fileNames = getFiles(photopath);
        
            time = extractBetween(fileNames,"_19","_ms");%extracted time strings from all files
            %convert everything to seconds
            hour_sec = 3600 * str2num((char((extractBetween(time, "_","h")))));
            minute_sec = 60 * str2num((char((extractBetween(time, "h","m")))));
            second = str2num((char((extractBetween(time, "m_","s")))));
            totseconds = hour_sec + minute_sec + second;
            
            %subtract seconds from first
            subtractsecond = totseconds - totseconds(1);
            
            %creates a new window
            graph_window = figure;
            ax = axes('Parent',graph_window);
            ax.Title.String = "The Average Intensity of Calcium Cell Over Time (seconds)";
            xlabel(ax,'Time (seconds)','FontSize',12)
            ylabel(ax,'Average Intensity of Calcium Cell','FontSize',12)
            hold(ax,'on')
            % remove the -1 part if you want to see the background also
            % graphed
            legend('location', 'best');
            for j = 1:(size(app.means,2)-1)
                %gets the data from the respective column per iteration
                col{j} = Array(:, j); 
                %plots the graph using the color on the cell drawing
                label = ['Cell# ',num2str(j)];
                plot(ax, subtractsecond, col{j}, colors{j}, 'DisplayName', label)
                %saves graph for next iteration
                hold on
            end
            %stops saving the graphs
            hold off
            
        end
        
        function means = getMeans(app, imArr)
            means = zeros(length(imArr), length(app.roi));
            
            for i = 1:length(imArr)
                file = imArr(i);
                fullFileName = fullfile(file.folder, file.name);
                imageArray = imread(fullFileName);
                
                for j = 1:length(app.roi)
                    mask = createMask(app.roi{j});
                    avgintensity = struct2array(regionprops(mask, imageArray,"MeanIntensity")); % gets the mean intensity for the mask within the image
                    means(i, j) = avgintensity(1);     
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes,'DataAspectRatio',[2 2 1])
            app.roi = {};
            app.cuts = {};
            
            app.numImages = 0;
        end

        % Button pushed function: LoadFileButton
        function LoadFIle(app, event)
            try
                app.myFolder = uigetdir('C:');  % get file
                figure(app.UIFigure); % focus window again
                
                if app.TOMCheckBox.Value == 1
                    app.filePattern = fullfile(app.myFolder + "\ToM", '*.tif'); % gets all the tif files
                else
                    app.filePattern = fullfile(app.myFolder + "\GFP", '*.tif'); % gets all the tif files
                end        
                
                app.tifFiles = dir(app.filePattern);
                
                app.numImages = length(app.tifFiles); % takes length and adjusts the slider
                app.ImageSlider.Limits = [1 app.numImages];
                
                if not(app.numImages)
                    return;
                end
            
                set(app.ImageSlider, 'value', 1) % sets slider
                displayImage(app, 1);
            catch
                warning("There was an error in selecting your files. Please try again.");
            end
        end

        % Value changing function: ImageSlider
        function ImageSliderValueChanging(app, event)
            if not(app.numImages)
                return;
            end
            
            y = event.Value;
            displayImage(app, y);
        end

        % Button pushed function: left
        function leftPressed(app, event)
            if not(app.numImages)
                return;
            end
            
            y = max(app.ImageSlider.Value-1, 1);
            displayImage(app, y);
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            if not(app.numImages)
                return;
            end
            
            y = min(app.ImageSlider.Value+1, app.numImages);
            displayImage(app, y);
        end

        % Callback function
        function PlayButtonPushed(app, event)
            app.play = not(app.play);
        end

        % Button pushed function: AddCellButton
        function AddCellButtonPushed(app, event)
            % answer = str2double(inputdlg("How many cells are in the Image?")); % takes number
            colors = ["r", "m", "y", "g", "b", "c", "w", "b"];
            i = length(app.roi)+1;
            app.Label.Text = "Draw Cell " + i;
            app.roi{i} = drawpolygon(app.UIAxes, "FaceAlpha", 0); %draws polygon
            % app.roi{i}.Color = colors{i};
            app.roi{i}.Label = sprintf("Cell %d",i);
            
            %app.Label.Text = "Draw backgorund";
            % app.roi{answer+1} = drawpolygon(app.UIAxes, "FaceAlpha", 0); %draws polygon
            
            x = app.ImageSlider.Value;
            baseFileName = app.tifFiles(round(x)).name;
            app.Label.Text = baseFileName;
            
            
        end

        % Callback function
        function ShowROIButtonPushed(app, event)
            showroi(app);
        end

        % Button pushed function: GetintensitiesButton
        function GetIntensities(app, event)
            % TODO - test
            
            app.Label.Text = "Working";
            drawnow;
            
            files = dir(fullfile(app.myFolder + "\GFP", '*.tif')); % gets tif files from \GFP directory
            m = app.getMeans(files); 
            
            mkdir(app.myFolder+"\data", "ROI")
            outputFolder = app.myFolder + "\data\Results.csv";
            writematrix(m, outputFolder);
            
            if app.TOMCheckBox.Value
                files = dir(fullfile(app.myFolder + "\ToM", '*.tif')); % gets tif files from \GFP directory
                m = app.getMeans(files); 
                
                outputFolder = app.myFolder + "\data\ResultsTOM.csv";
                writematrix(m, outputFolder);
            end
            
            %{
            app.means = zeros(app.numImages, length(app.roi));
           
            for i = 1:app.numImages
                app.filePattern = fullfile(app.myFolder + "\GFP", '*.tif'); % gets all the tif files
                app.tifFiles = dir(app.filePattern);
                
                baseFileName = app.tifFiles(i).name;
                fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);
                imageArray = imread(fullFileName); %gets the image since regionprops doesn't work with UIAxes
                for j = 1:length(app.roi)
                    mask = createMask(app.roi{j});
                    avgintensity = struct2array(regionprops(mask, imageArray,"MeanIntensity")); % gets the mean intensity for the mask within the image
                    % disp(avgintensity);
                    app.means(i, j) = avgintensity(1);
                    
                end
            end
            mkdir(app.myFolder, 'data');
            mkdir(app.myFolder+"\data", "ROI")
            % disp(app.means)
            outputFolder = app.myFolder + "\data\Results.csv";
            writematrix(app.means, outputFolder);
            
            if app.TOMCheckBox.Value == 1
                disp(app.myFolder);
                app.filePattern = fullfile(app.myFolder + "\ToM", '*.tif'); % gets all the tif files
                app.tifFiles = dir(app.filePattern);
                for i = 1:app.numImages
                    baseFileName = app.tifFiles(i).name;
                    fullFileName = fullfile(app.myFolder + "\TOM", baseFileName);
                    imageArray = imread(fullFileName); %gets the image since regionprops doesn't work with UIAxes
                    for j = 1:length(app.roi)
                        mask = createMask(app.roi{j});
                        avgintensity = struct2array(regionprops(mask, imageArray,"MeanIntensity")); % gets the mean intensity for the mask within the image
                        % disp(avgintensity);
                        app.means(i, j) = avgintensity(1);
                        
                    end
                end
               % disp(app.means)
                outputFolder = app.myFolder + "\data\ResultsTOM.csv";
                writematrix(app.means, outputFolder);
            end
            %}
            
            % displayImage(app, app.ImageSlider.Value);
        end

        % Button pushed function: PopupandHalflifeButton
        function PopupandHalflifeButtonPushed(app, event)
            % Input the cuts
            % answer = str2double(inputdlg("How many cuts are there"));
            % for i = 1:answer
            %     app.cuts{i} = str2double(inputdlg("Which image is the cut"));
            % end
            
            %Take Plateau Range after Cut
            % PlateauRangeAfterPeaks = str2double(inputdlg("Plateau Range After Peaks"));
            
            app.Label.Text = "Working";
            drawnow;
            
            PlateauRangeAfterPeaks = str2double(app.PlateauRangeAfterPeaksEditField.Value);
            app.cuts = str2double(split(app.CutsSeparatedbyspacesEditField.Value));
            disp(app.cuts);
            
            function fileList = getFiles(dirName)
            dirData = dir(dirName);      %# Get the data for the current directory
            dirIndex = [dirData.isdir];  %# Find the index for directories
            fileList = {dirData(~dirIndex).name}';  %'# Get a list of the files
            end
            done = "done";

            tomval = app.TOMCheckBox.Value;
            firstcut_index = app.cuts(1);
            % disp(firstcut_index);
                
            gfpfilepath = app.myFolder + "\data\Results.csv";
            tomfilepath = app.myFolder + "\data\ResultsTOM.csv";
            
            data = csvread(gfpfilepath);
            if tomval == 1
                tomdata = csvread(tomfilepath);
            end

            columntot = length(app.roi);
            operatecolumn = length(app.roi)-1;
            photopath = app.myFolder +"\GFP";
            writeto = app.myFolder + "\data\ROI";
        
            fileNames = getFiles(photopath);
        
            time = extractBetween(fileNames,"_"+app.Yearlast2EditField.Value,"_ms");%extracted time strings from all files
            %convert everything to seconds
            hour_sec = 3600 * str2num((char((extractBetween(time, "_","h")))));
            minute_sec = 60 * str2num((char((extractBetween(time, "h","m")))));
            second = str2num((char((extractBetween(time, "m_","s")))));
            totseconds = hour_sec + minute_sec + second;
            
            plot(totseconds) % to check
            
            %subtract seconds from first
            subtractsecond = totseconds - totseconds(1);

            if tomval == 1
                GFPoperatedata = data(:,1:operatecolumn);
                GFPMean = GFPoperatedata - data(:, columntot);
                TOMoperatedata = tomdata(:,1:operatecolumn);
                TOMMean = TOMoperatedata - tomdata(:, columntot);
                Mean = GFPMean ./ TOMMean;
                row = Mean(firstcut_index,:);
                Mean = Mean ./ row;
%                     output = array2table(Mean);
                for i = 1:operatecolumn
                    a = zeros(size(Mean,1),2);
                    a(:,1) = subtractsecond(:,1);
                    a(:,2) = Mean(:,i);
                    meanheader = strcat('Mean',num2str(i));
                    output = array2table(a,'VariableNames',{'Time',meanheader});
                    finalwrite = strcat(writeto,'/Normalize_TOM_Divide ',num2str(firstcut_index), meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                    writetable(output,finalwrite);
                end
                done
%                     sheetname = strcat('Normalize Divide ',app.RowNumberEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
            else % dividing and normalizing
                operatedata = data(:,1:operatecolumn);
                Mean = operatedata - data(:, columntot);
                row = Mean(firstcut_index,:);
                Mean = Mean ./ row;
%                     output = array2table(Mean);
                for i = 1:operatecolumn
                    a = zeros(size(Mean,1),2);
                    a(:,1) = subtractsecond(:,1);
                    a(:,2) = Mean(:,i);
                    meanheader = strcat('Mean',num2str(i));
                    output = array2table(a,'VariableNames',{'Time',meanheader});
                    finalwrite = strcat(writeto,'/Normalize_Divide ',num2str(firstcut_index), meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                    writetable(output,finalwrite);
                end
                done
%                     sheetname = strcat('Normalize Divide ',app.RowNumberEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
            end
            
            for cutnum = 1:length(app.cuts)
                %Half-Life Analysis Starts
                root1 = app.myFolder + "\data\";
                root2 = "ROI\";
                root = root1 + root2;
                filetype = '.csv';
                final = zeros(1,2); %creates an empty matrix to be filled later with values
                cutindex = app.cuts(cutnum);
                %nextcutval = app.NottheLastCutCheckBox.Value;
                %tomval = app.TOMCheckBox.Value;
    
                
                for i = 1:length(app.roi)-1
                    if tomval == 1
                        filenum =  "Normalize_TOM_Divide" + num2str(firstcut_index) + "Mean" + num2str(i);
                    else
                        filenum =  "Normalize_Divide" + num2str(firstcut_index) + "Mean" + num2str(i);
                    end
                    
                    filepath = strcat(root+filenum+filetype); %this creates/puts together the filename
                    disp(filepath);
                    wholematrix = csvread(filepath, 1, 0); %reads the files
                    x = wholematrix(:,1); %creates an array of x values (times)
                    y = wholematrix(:,2); %creates an array of y values (intensities)
                    
                    %if nextcutval == 1
                    %    next_cutindex = app.NextCutIndexEditField.Value;
                    %else
                    %    next_cutindex = numel(x);
                    %end
                    
                    if cutnum < length(app.cuts)
                        next_cutindex = app.cuts(cutnum+1);
                    else
                        next_cutindex = app.numImages;
                    end
                    
                    cuttime = x(cutindex);
                    next_cuttime = x(next_cutindex);
                    cy = y(cutindex:end); 
                    cx = x(cutindex:end);
    
        %FIND PEAK
        %----------------------------------------
                    last_image_time = cx(end,:);
                    peakrange_time = cuttime + 45;
                    if peakrange_time > last_image_time
                        peakrange_time = last_image_time;
                    end
                    after_peakrange = find(cx>=peakrange_time); %this takes all the indexes of all the intensities in findrange that are less than or equal to the peak value
                    peakrange_onsheet = cx(after_peakrange(1)); %after_peakrange(1) is essentially the index of the first value after the peakrange
                    peakrange_index = find(x==peakrange_onsheet,1, 'first'); %this takes the actual index of the peakrange
        
                    last_image_index = find(x==last_image_time);
                    cy_peak_range = y(cutindex:peakrange_index);%creates a new intensity array that only contains possible range of peak.
    
                    peak = max(cy_peak_range); %finds the value of the intensity of the peak
                    peakindex = find(cy==peak,1,'first'); %indexes the value so we can locate the corresponding time in "cx" for this peak
        %----------------------------------------
        
        %FIND PLATEAU
        %----------------------------------------
                    peaktime = cx(peakindex); %locates the corresponding time to the peak and saves that value
        %NEED A IF STATEMENT HERE
        
                    plateaurange_time = peaktime + PlateauRangeAfterPeaks;
                    if plateaurange_time > next_cuttime
                        plateaurange_onsheet = next_cuttime;
                    else
                        after_plateaurange = find(cx>=plateaurange_time); %this takes all the indexes of all the intensities in findrange that are less than or equal to the plateau value
                        plateaurange_onsheet = cx(after_plateaurange(1)); %after_plateaurange(1) is essentially the index of the first value after the plateaurange
                    end
                    plateaurange_index = find(cx==plateaurange_onsheet); %,1, 'first'this takes the actual index of the plateaurange
                    plateau = min(cy(peakindex:plateaurange_index)); %find the mininum between the peak and the following cut as the plateau
        
                    plateau_index = find(cy==plateau,1,'first');%find the index location of the plateau
        %mihgt have error here if there is no peak.
                    if plateau_index < peakindex %In case there is a image before peak which has extact same intensity as plateau
                        plateau_index = find(cy==plateau,1,'last');%find the index location of the plateau
                    end 
        %----------------------------------------
    
        %FITTING
        %----------------------------------------
                    intensity_array = cy(peakindex:plateau_index); %create an intensity array from the peak to the plateau
                    time_array = cx(peakindex:plateau_index); %create an time array from the peak to the plateau
            
                    plateau_time = cx(plateau_index);
            
                    points_num = numel(intensity_array);%number of intensity array elements
                    try
                        opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
                        opts.Display = 'Off';
                        %opts.Normalize = 'on';
                    
                        [f, gof] = fit(intensity_array,time_array,'exp2',opts); %2 phase exponential decay fitting
               
                        half_peak = (peak - plateau)/2 + plateau;
                        half_life = f(half_peak) - peaktime;
            
                        r2 = gof.rsquare; %Obtain R-square to examine the goodness of fitting.
                    catch %Any error goes to NA
                        half_life = "NA";
                        r2 = "NA";
                    end
        %----------------------------------------
                    final(i,1) = i; %this serves as a label to identify the time with the csv file
                    final(i,2) = peaktime;
                    final(i,3) = peak;
                    final(i,4) = plateau_time; %saves the subtracted time between the peaks to a location in the matrix
                    final(i,5) = plateau;
                    final(i,6) = half_life;
                    final(i,7) = r2;
                    final(i,8) = points_num;
                end
                output = array2table(final,'VariableNames',{'Cell','Peak_Time','Peak_Value','Plateau_Time','Plateau_Value','Half_Life','Rsquare','Num_Points'}); %writes the matrix containing all subtracted times to a single csv file containing all the times
    
                output_filename = "Half-life for the "+cuttime+"s cut";
    
                finalwrite = strcat(root1,output_filename,filetype); %will write to the location where all the csv files are
    
                writetable(output,finalwrite);
            end
            
            app.caDirectory = app.myFolder+"\data\ROI";
            app.caFiles = dir(fullfile(app.caDirectory, "*.csv"));            
            app.ChooseDirectoryButton.Text = app.caDirectory;
            
            app.ListBox.Items = arrayfun(@(x) x.name, app.caFiles, 'uniformoutput', false);
            
            if app.AllCheckBox.Value
                if ~isempty(app.ListBox.Items) % TODO - copied code, maybe make helper?
                    app.NeuronsEditField.Value = strcat('1:', num2str(length(app.ListBox.Items)));
                else
                    app.NeuronsEditField.Value = '0';
                end
            end
            
            displayImage(app, app.ImageSlider.Value);
            
            % if notfirstcutval == 1
            %     firstcut_index = app.FirstCutIndexEditField.Value;
            % else
               
            % end
            
        end

        % Button pushed function: ChooseDirectoryButton
        function ChooseDirectoryButtonPushed(app, event)
            app.caDirectory = uigetdir;
            figure(app.UIFigure); % focus window again
            
            app.caFiles = dir(fullfile(app.caDirectory, "*.csv"));            
            app.ChooseDirectoryButton.Text = app.caDirectory;
            
            app.ListBox.Items = arrayfun(@(x) x.name, app.caFiles, 'uniformoutput', false);
            
            if app.AllCheckBox.Value
                if ~isempty(app.ListBox.Items) % TODO - copied code, maybe make helper?
                    app.NeuronsEditField.Value = strcat('1:', num2str(length(app.ListBox.Items)));
                else
                    app.NeuronsEditField.Value = '0';
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            directory = app.caDirectory;
            files = app.ListBox.Items;
            method = 2;
            
            if isempty(app.NeuronsEditField.Value)
                if ~isempty(app.ListBox.Items) % TODO - copied code, maybe make helper?
                    app.NeuronsEditField.Value = strcat('1:', num2str(length(app.ListBox.Items)));
                else
                    app.NeuronsEditField.Value = '0';
                end
            end
            
            neurons = str2num(app.NeuronsEditField.Value);
            neurons = neurons(0 < neurons & neurons < length(app.ListBox.Items) + 1);
            
            append = strcmp(app.DropDown.Value, 'Append to Output');
            stim_pos = 0; % TODO - figure out how to add stimulation
            
            user_data = [{app.AcquisitionEditField.Value} {app.DaysinVitroEditField.Value} {app.GenotyDropDown.Value} {app.DishEditField.Value} {app.TrialEditField.Value}, ...
                {app.MagnificatDropDown.Value} {app.ChambDropDown.Value} {app.FramerateEditField.Value}, ...
                {app.BitDepthDropDown.Value} {app.MinHeightEditField.Value} {app.ProminenceEditField.Value}];
            write = strcmp(app.DropDown.Value, 'Write to Output');
            
            Analyze_Calcium_Activity(directory,files,method,neurons,append,stim_pos,user_data,write);
        end

        % Value changed function: AllCheckBox
        function AllCheckBoxValueChanged(app, event)
            value = app.AllCheckBox.Value;
            
            if value
                if ~isempty(app.ListBox.Items)
                    app.NeuronsEditField.Value = strcat('1:', num2str(length(app.ListBox.Items)));
                else
                    app.NeuronsEditField.Value = '0';
                end
                
                app.NeuronsEditField.Editable = false;
            else
                app.NeuronsEditField.Editable = true;
            end
        end

        % Callback function
        function InputCutsButtonPushed(app, event)
            % TODO - check what this func does
            
            answer = str2double(inputdlg("How many cuts are there"));
            for i = 1:answer
                app.cuts{i} = str2double(inputdlg("Which image is the cut"));
            end
        end

        % Callback function
        function TrimButtonPushed(app, event)
            % TODO - check what this func does
            
            outputFolder = uigetdir('C:'); % takes in a directory for output
            answer = inputdlg("number of files in output"); % takes number
            trimfiles(app.myFolder + "\GFP", outputFolder, str2double(answer)); % calls function
            
            app.myFolder = outputFolder;
            app.filePattern = fullfile(app.myFolder, '*.tif');
            app.tifFiles = dir(app.filePattern);
            app.play = false;
            
            len = length(app.tifFiles);
            app.ImageSlider.Limits = [0 len-1];
            
            set(app.ImageSlider, 'value', 1)
            baseFileName = app.tifFiles(1).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging(app, event)
            changingValue = event.Value;
           app.filePattern = fullfile(app.myFolder, '*.tif'); % gets all the tif files
                app.tifFiles = dir(app.filePattern);
       
                baseFileName = app.tifFiles(1).name; % gets specific image
                fullFileName = fullfile(app.myFolder, baseFileName);
                imageArray = imread(fullFileName);
                 imbright = imageArray + changingValue*100; %adds changing value to image value
                 %increase * 100 to increase brightness more
                 
                app.Label.Text = baseFileName; % displays image on axis and displays name
                % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
                % Display image and stretch to fill axes
                I = imshow(imbright, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                drawnow;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadImagesROITab = uitab(app.TabGroup);
            app.LoadImagesROITab.Title = 'Load Images + ROI';
            app.UIAxes = uiaxes(app.LoadImagesROITab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImageLabel = uilabel(app.LoadImagesROITab);
            app.ImageLabel.Text = 'Image';
            app.ImageSlider = uislider(app.LoadImagesROITab);
            app.ImageSlider.Limits = [0 200];
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderValueChanging, true);
            app.ImageSlider.Value = 1;
            app.Label = uilabel(app.LoadImagesROITab);
            app.Label.Text = '';
            app.LoadFileButton = uibutton(app.LoadImagesROITab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFIle, true);
            app.LoadFileButton.Text = {'Load File'; ''};
            app.left = uibutton(app.LoadImagesROITab, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPressed, true);
            app.left.Text = '<';
            app.right = uibutton(app.LoadImagesROITab, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '->';
            app.AddCellButton = uibutton(app.LoadImagesROITab, 'push');
            app.AddCellButton.ButtonPushedFcn = createCallbackFcn(app, @AddCellButtonPushed, true);
            app.AddCellButton.Text = {'Add Cell'; ''};
            app.TOMCheckBox = uicheckbox(app.LoadImagesROITab);
            app.TOMCheckBox.Text = 'TOM';
            app.PopupandHalflifeButton = uibutton(app.LoadImagesROITab, 'push');
            app.PopupandHalflifeButton.ButtonPushedFcn = createCallbackFcn(app, @PopupandHalflifeButtonPushed, true);
            app.PopupandHalflifeButton.Text = 'Popup and Halflife';
            app.GetintensitiesButton = uibutton(app.LoadImagesROITab, 'push');
            app.GetintensitiesButton.ButtonPushedFcn = createCallbackFcn(app, @GetIntensities, true);
            app.GetintensitiesButton.Text = 'Get intensities';
            app.PlateauRangeAfterPeaksEditFieldLabel = uilabel(app.LoadImagesROITab);
            app.PlateauRangeAfterPeaksEditFieldLabel.Text = 'Plateau Range After Peaks';
            app.PlateauRangeAfterPeaksEditField = uieditfield(app.LoadImagesROITab, 'text');
            app.PlateauRangeAfterPeaksEditField.Value = '60';
            app.CutsSeparatedbyspacesEditField_2Label = uilabel(app.LoadImagesROITab);
            app.CutsSeparatedbyspacesEditField_2Label.Text = 'Cuts (Separated by spaces)';
            app.CutsSeparatedbyspacesEditField = uieditfield(app.LoadImagesROITab, 'text');
            app.CutsSeparatedbyspacesEditField.Value = '60';
            app.Yearlast2EditFieldLabel = uilabel(app.LoadImagesROITab);
            app.Yearlast2EditFieldLabel.Text = {'Year (last 2)'; ''};
            app.Yearlast2EditField = uieditfield(app.LoadImagesROITab, 'text');
            app.Yearlast2EditField.Value = '19';
            app.BrightnessSliderLabel = uilabel(app.LoadImagesROITab);
            app.BrightnessSliderLabel.Text = 'Brightness Slider';
            app.BrightnessSlider = uislider(app.LoadImagesROITab);
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging, true);
            app.ExtractPeakParametersTab = uitab(app.TabGroup);
            app.ExtractPeakParametersTab.Title = 'Extract Peak Parameters';
            app.MinHeightEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.MinHeightEditFieldLabel.Text = 'Min Height';
            app.ChooseDirectoryButton = uibutton(app.ExtractPeakParametersTab, 'push');
            app.ChooseDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDirectoryButtonPushed, true);
            app.ChooseDirectoryButton.Text = 'Choose Directory';
            app.ListBox = uilistbox(app.ExtractPeakParametersTab);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.MinHeightEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.MinHeightEditField.Value = '0.1';
            app.ProminenceEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.ProminenceEditFieldLabel.Text = 'Prominence';
            app.ProminenceEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.ProminenceEditField.Value = '0.1';
            app.NeuronsEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.NeuronsEditFieldLabel.Text = 'Neurons';
            app.NeuronsEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.AllCheckBox = uicheckbox(app.ExtractPeakParametersTab);
            app.AllCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllCheckBoxValueChanged, true);
            app.AllCheckBox.Text = 'All';
            app.DropDown = uidropdown(app.ExtractPeakParametersTab);
            app.DropDown.Items = {'Display', 'Write to Output', 'Append to Output'};
            app.DropDown.Value = 'Display';
            app.RunButton = uibutton(app.ExtractPeakParametersTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DaysinVitroEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.DaysinVitroEditFieldLabel.Text = 'Days in Vitro';
            app.DaysinVitroEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.GenotyDropDownLabel = uilabel(app.ExtractPeakParametersTab);
            app.GenotyDropDownLabel.Text = 'Genoty';
            app.GenotyDropDown = uidropdown(app.ExtractPeakParametersTab);
            app.GenotyDropDown.Items = {'WT', 'BACHD', 'N17'};
            app.GenotyDropDown.Value = 'WT';
            app.FramerateEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.FramerateEditFieldLabel.Text = 'Framerate';
            app.FramerateEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.FramerateEditField.Value = '1';
            app.BitDepthDropDownLabel = uilabel(app.ExtractPeakParametersTab);
            app.BitDepthDropDownLabel.Text = 'Bit-Depth';
            app.BitDepthDropDown = uidropdown(app.ExtractPeakParametersTab);
            app.BitDepthDropDown.Items = {'8', '10', '12', '14', '16'};
            app.BitDepthDropDown.Value = '16';
            app.TrialEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.DishEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.DishEditFieldLabel.Text = 'Dish';
            app.DishEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.ChambDropDownLabel = uilabel(app.ExtractPeakParametersTab);
            app.ChambDropDownLabel.Text = 'Chamb';
            app.ChambDropDown = uidropdown(app.ExtractPeakParametersTab);
            app.ChambDropDown.Items = {'Microwell', 'Microfluidic (Open)', 'Microfluidic (Closed)'};
            app.ChambDropDown.Value = 'Microwell';
            app.MagnificatDropDownLabel = uilabel(app.ExtractPeakParametersTab);
            app.MagnificatDropDownLabel.Text = 'Magnificat';
            app.MagnificatDropDown = uidropdown(app.ExtractPeakParametersTab);
            app.MagnificatDropDown.Items = {'20X', '40X'};
            app.MagnificatDropDown.Value = '20X';
            app.AcquisitionEditFieldLabel = uilabel(app.ExtractPeakParametersTab);
            app.AcquisitionEditFieldLabel.Text = 'Acquisition';
            app.AcquisitionEditField = uieditfield(app.ExtractPeakParametersTab, 'text');
            app.AcquisitionEditField.Value = 'mm/dd/yyyy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CalciumProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end