classdef popup7_31_19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ToMCheckBox                  matlab.ui.control.CheckBox
        ToMFilePathEditField         matlab.ui.control.EditField
        ToMFilePathEditFieldLabel    matlab.ui.control.Label
        WriteToEditField             matlab.ui.control.EditField
        WriteToEditFieldLabel        matlab.ui.control.Label
        ImageFilePathEditField       matlab.ui.control.EditField
        ImageFilePathEditFieldLabel  matlab.ui.control.Label
        GFPFilePathEditField         matlab.ui.control.EditField
        GFPFilePathEditFieldLabel    matlab.ui.control.Label
        TotalColumnsEditField        matlab.ui.control.NumericEditField
        TotalColumnsEditFieldLabel   matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        CannotExceedRowLimitLabel    matlab.ui.control.Label
        RowNumberEditField           matlab.ui.control.EditField
        RowNumberEditFieldLabel      matlab.ui.control.Label
        NormalizationCheckBox        matlab.ui.control.CheckBox
        EnterButton                  matlab.ui.control.Button
        OperationDropDown            matlab.ui.control.DropDown
        OperationDropDownLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            function fileList = getFiles(dirName)
             dirData = dir(dirName);      %# Get the data for the current directory
             dirIndex = [dirData.isdir];  %# Find the index for directories
             fileList = {dirData(~dirIndex).name}';  %'# Get a list of the files
            end
            
            
            normval = app.NormalizationCheckBox.Value;
            tomval = app.ToMCheckBox.Value;
            gfpfilepath = app.GFPFilePathEditField.Value;
            tomfilepath = app.ToMFilePathEditField.Value;
%             filename = filepath;
            data = csvread(gfpfilepath,1,2);
            tomdata = csvread(tomfilepath, 1,2);
%             data = rawdata(:,3:end);
            columntot = app.TotalColumnsEditField.Value;
            rownum = str2double(app.RowNumberEditField.Value);
            command = app.OperationDropDown.Value;
            operatecolumn = columntot-1;
            photopath = app.ImageFilePathEditField.Value;
            writeto = app.WriteToEditField.Value;
            
            
            %clear workspace
%             clear all, close all;
            %extracted all file image names from folder
            fileNames = getFiles(photopath);
            %extracted time strings from all files
            time = extractBetween(fileNames,"19","_ms");
            %convert everything to seconds
            hour_sec = 3600 * str2num((char((extractBetween(time, "_","h")))));
            minute_sec = 60 * str2num((char((extractBetween(time, "h","m")))));
            second = str2num((char((extractBetween(time, "m_","s")))));
            totseconds = hour_sec + minute_sec + second;
            
            %plot(totseconds) to check
            
            %subtract seconds from first
            subtractsecond = totseconds - totseconds(1);
            
            %plot(subtractsecond) to check
            
            %write to a csv file
%             fid = fopen(writeto, 'wt');
%             fprintf(fid, '%s\n', 'Time');
%             fprintf(fid, '%d\n', subtractsecond);   %transpose is important!
%             fclose(fid);
            
            
            if command == "Division"
                if normval == 0 % dividing columns
                    operatedata = data(:,1:operatecolumn);
                    Mean = operatedata ./ data(:, columntot);
%                     dresultdata = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Column_Divide ',app.RowNumberEditField.Value, meanheader,".csv");
                        writetable(output, finalwrite);
                    end
%                     sheetname = strcat('Column Divide ',app.RowNumberEditField.Value);
%                     writetable(dresultdata,filename,'Sheet',sheetname)                
                end
                if normval == 1 % dividing and normalizing
                    operatedata = data(:,1:operatecolumn);
                    Mean = operatedata - data(:, columntot);
                    row = Mean(rownum,:);
                    Mean = Mean ./ row;
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Normalize_Divide ',app.RowNumberEditField.Value, meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
%                     sheetname = strcat('Normalize Divide ',app.RowNumberEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
                end
                if tomval == 1 && normval == 1
                    GFPoperatedata = data(:,1:operatecolumn);
                    GFPMean = GFPoperatedata - data(:, columntot);
                    TOMoperatedata = tomdata(:,1:operatecolumn);
                    TOMMean = TOMoperatedata - data(:, columntot);
                    Mean = GFPMean ./ TOMMean;
                    row = Mean(rownum,:);
                    Mean = Mean ./ row;
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Normalize_TOM_Divide ',app.RowNumberEditField.Value, meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
%                     sheetname = strcat('Normalize Divide ',app.RowNumberEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
                end
            end
            if command == "Subtract"
                if normval == 0 % subtracting columns
                    operatedata = data(:,1:operatecolumn);
                    Mean = operatedata - data(:, columntot);
%                     sresultdata = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Column_Subtract ',app.RowNumberEditField.Value, meanheader,".csv");
                        writetable(output, finalwrite);
                    end
%                     sheetname = strcat('Column Subtract ',app.RowNumberEditField.Value);
%                     writetable(sresultdata,filename,'Sheet',sheetname)
                end
                if normval == 1 % subtracting and normalizing columns
                    operatedata = data(:,1:operatecolumn)
                    Mean = operatedata - data(:, columntot)
                    row = Mean(rownum,:)
                    Mean = Mean - row
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2)
                        a(:,1) = subtractsecond(:,1)
                        a(:,2) = Mean(:,i)
                        meanheader = strcat('Mean',num2str(i))
                        output = array2table(a,'VariableNames',{'Time',meanheader})
                        finalwrite = strcat(writeto,'/Normalize_Subtract ',app.RowNumberEditField.Value, meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
%                     sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value);
%                     writetable(output,filename,'Sheet',sheetname)
                end
                if tomval == 1 && normval == 1
                    GFPoperatedata = data(:,1:operatecolumn);
                    GFPMean = GFPoperatedata - data(:, columntot);
                    TOMoperatedata = tomdata(:,1:operatecolumn);
                    TOMMean = TOMoperatedata - data(:, columntot);
                    Mean = GFPMean ./ TOMMean;
                    row = Mean(rownum,:);
                    Mean = Mean - row;
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Normalize_TOM_Subtract ',app.RowNumberEditField.Value, meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
%                     sheetname = strcat('Normalize Divide ',app.RowNumberEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
                end
            end
        end

        % Callback function
        function EnterButton_2Pushed(app, event)

        end

        % Value changed function: NormalizationCheckBox
        function NormalizationCheckBoxValueChanged(app, event)
            value = app.NormalizationCheckBox.Value;
            
            switch value
                case 0
                    app.Panel.Visible = 'off';
                case 1
                    app.Panel.Visible = 'on';
            end
        end

        % Value changed function: ToMCheckBox
        function ToMCheckBoxValueChanged(app, event)
            value = app.ToMCheckBox.Value;
            
            switch value
                case 0
                    app.ToMFilePathEditField.Visible = 'off';
                    app.ToMFilePathEditFieldLabel.Visible = 'off';
                case 1
                    app.ToMFilePathEditField.Visible = 'on';
                    app.ToMFilePathEditFieldLabel.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OperationDropDownLabel = uilabel(app.UIFigure);
            app.OperationDropDownLabel.Text = 'Operation';
            app.OperationDropDown = uidropdown(app.UIFigure);
            app.OperationDropDown.Items = {'Division', 'Subtract'};
            app.OperationDropDown.Value = 'Division';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.NormalizationCheckBox = uicheckbox(app.UIFigure);
            app.NormalizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizationCheckBoxValueChanged, true);
            app.NormalizationCheckBox.Text = 'Normalization';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.RowNumberEditFieldLabel = uilabel(app.Panel);
            app.RowNumberEditFieldLabel.Text = 'Row Number';
            app.RowNumberEditField = uieditfield(app.Panel, 'text');
            app.CannotExceedRowLimitLabel = uilabel(app.Panel);
            app.CannotExceedRowLimitLabel.Text = 'Row Must Be Within Range';
            app.TotalColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalColumnsEditFieldLabel.Text = 'Total Columns';
            app.TotalColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.GFPFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.GFPFilePathEditFieldLabel.Text = 'GFP File Path';
            app.GFPFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.ImageFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.ImageFilePathEditFieldLabel.Text = 'Image File Path';
            app.ImageFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.WriteToEditFieldLabel = uilabel(app.UIFigure);
            app.WriteToEditFieldLabel.Text = 'Write To';
            app.WriteToEditField = uieditfield(app.UIFigure, 'text');
            app.ToMFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.ToMFilePathEditFieldLabel.Visible = 'off';
            app.ToMFilePathEditFieldLabel.Text = 'ToM File Path';
            app.ToMFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.ToMFilePathEditField.Visible = 'off';
            app.ToMCheckBox = uicheckbox(app.UIFigure);
            app.ToMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToMCheckBoxValueChanged, true);
            app.ToMCheckBox.Text = 'ToM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = popup7_31_19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end