classdef popupold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OperationDropDownLabel       matlab.ui.control.Label
        OperationDropDown            matlab.ui.control.DropDown
        EnterButton                  matlab.ui.control.Button
        NormalizationCheckBox        matlab.ui.control.CheckBox
        Panel                        matlab.ui.container.Panel
        RowNumberEditFieldLabel      matlab.ui.control.Label
        RowNumberEditField           matlab.ui.control.EditField
        CannotExceedRowLimitLabel    matlab.ui.control.Label
        TotalColumnsEditFieldLabel   matlab.ui.control.Label
        TotalColumnsEditField        matlab.ui.control.NumericEditField
        DataFilePathEditFieldLabel   matlab.ui.control.Label
        DataFilePathEditField        matlab.ui.control.EditField
        ImageFilePathEditFieldLabel  matlab.ui.control.Label
        ImageFilePathEditField       matlab.ui.control.EditField
        WriteToEditFieldLabel        matlab.ui.control.Label
        WriteToEditField             matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            function fileList = getFiles(dirName)
             dirData = dir(dirName);      %# Get the data for the current directory
             dirIndex = [dirData.isdir];  %# Find the index for directories
             fileList = {dirData(~dirIndex).name}';  %'# Get a list of the files
            end
            
            
            val = app.NormalizationCheckBox.Value;
            filepath = app.DataFilePathEditField.Value;
%             filename = filepath;
            rawdata = xlsread(filepath);
            data = rawdata(:,3:end);
            columntot = app.TotalColumnsEditField.Value;
            rownum = str2double(app.RowNumberEditField.Value);
            command = app.OperationDropDown.Value;
            operatecolumn = columntot-1;
            photopath = app.ImageFilePathEditField.Value;
            writeto = app.WriteToEditField.Value;
            
            
            %clear workspace
%             clear all, close all;
            %extracted all file image names from folder
            fileNames = getFiles(photopath);
            %extracted time strings from all files
            time = extractBetween(fileNames,"19","_ms");
            %convert everything to seconds
            hour_sec = 3600 * str2num((char((extractBetween(time, "_","h")))));
            minute_sec = 60 * str2num((char((extractBetween(time, "h","m")))));
            second = str2num((char((extractBetween(time, "m_","s")))));
            totseconds = hour_sec + minute_sec + second;
            
            %plot(totseconds) to check
            
            %subtract seconds from first
            subtractsecond = totseconds - totseconds(1);
            
            %plot(subtractsecond) to check
            
            %write to a csv file
%             fid = fopen(writeto, 'wt');
%             fprintf(fid, '%s\n', 'Time');
%             fprintf(fid, '%d\n', subtractsecond);   %transpose is important!
%             fclose(fid);
            
            
            if command == "Division"
                if val == 0
                    operatedata = data(:,1:operatecolumn);
                    Mean = operatedata ./ data(:, columntot);
%                     dresultdata = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Column_Divide ',app.RowNumberEditField.Value, meanheader,".csv");
                        writetable(output, finalwrite);
                    end
%                     sheetname = strcat('Column Divide ',app.RowNumberEditField.Value);
%                     writetable(dresultdata,filename,'Sheet',sheetname)                
                end
                if val == 1
                    operatedata = data(:, 1:operatecolumn);
                    Mean = operatedata - data(:, columntot);
                    row = Mean(rownum,:);
                    Mean = Mean ./ row;
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Normalize_Divide ',app.RowNumberEditField.Value, meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
%                     sheetname = strcat('Normalize Divide ',app.RowNumberEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
                end
            end
            if command == "Subtract"
                if val == 0
                    operatedata = data(:,1:operatecolumn);
                    Mean = operatedata - data(:, columntot);
%                     sresultdata = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Column_Subtract ',app.RowNumberEditField.Value, meanheader,".csv");
                        writetable(output, finalwrite);
                    end
%                     sheetname = strcat('Column Subtract ',app.RowNumberEditField.Value);
%                     writetable(sresultdata,filename,'Sheet',sheetname)
                end
                if val == 1
                    operatedata = data(:,1:operatecolumn);
                    Mean = operatedata - data(:, columntot);
                    row = Mean(rownum,:);
                    Mean = Mean - row;
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Normalize_Subtract ',app.RowNumberEditField.Value, meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
%                     sheetname = strcat('Normalize Subtract ',app.RowNumberEditField.Value);
%                     writetable(output,filename,'Sheet',sheetname)
                end
            end
        end

        % Callback function
        function EnterButton_2Pushed(app, event)
            
        end

        % Value changed function: NormalizationCheckBox
        function NormalizationCheckBoxValueChanged(app, event)
            value = app.NormalizationCheckBox.Value;
            
            switch value
                case 0
                    app.Panel.Visible = 'off';
                case 1
                    app.Panel.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OperationDropDownLabel = uilabel(app.UIFigure);
            app.OperationDropDownLabel.Text = 'Operation';
            app.OperationDropDown = uidropdown(app.UIFigure);
            app.OperationDropDown.Items = {'Division', 'Subtract'};
            app.OperationDropDown.Value = 'Division';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.NormalizationCheckBox = uicheckbox(app.UIFigure);
            app.NormalizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizationCheckBoxValueChanged, true);
            app.NormalizationCheckBox.Text = 'Normalization';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.RowNumberEditFieldLabel = uilabel(app.Panel);
            app.RowNumberEditFieldLabel.Text = 'Row Number';
            app.RowNumberEditField = uieditfield(app.Panel, 'text');
            app.CannotExceedRowLimitLabel = uilabel(app.Panel);
            app.CannotExceedRowLimitLabel.Text = 'Row Must Be Within Range';
            app.TotalColumnsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalColumnsEditFieldLabel.Text = 'Total Columns';
            app.TotalColumnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DataFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.DataFilePathEditFieldLabel.Text = 'Data File Path';
            app.DataFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.ImageFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.ImageFilePathEditFieldLabel.Text = 'Image File Path';
            app.ImageFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.WriteToEditFieldLabel = uilabel(app.UIFigure);
            app.WriteToEditFieldLabel.Text = 'Write To';
            app.WriteToEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = popupold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end