classdef HalfLife_and_cuttopeak_2_26_20 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DataFolderPathEditFieldLabel  matlab.ui.control.Label
        DataFolderPathEditField       matlab.ui.control.EditField
        NottheLastCutCheckBox         matlab.ui.control.CheckBox
        EnterButton                   matlab.ui.control.Button
        TOMCheckBox                   matlab.ui.control.CheckBox
        NottheFirstCutCheckBox        matlab.ui.control.CheckBox
        CutIndexEditFieldLabel        matlab.ui.control.Label
        CutIndexEditField             matlab.ui.control.NumericEditField
        NumberofROIsEditFieldLabel    matlab.ui.control.Label
        NumberofROIsEditField         matlab.ui.control.NumericEditField
        NextCutIndexEditFieldLabel    matlab.ui.control.Label
        NextCutIndexEditField         matlab.ui.control.NumericEditField
        FirstCutIndexEditFieldLabel   matlab.ui.control.Label
        FirstCutIndexEditField        matlab.ui.control.NumericEditField
        PlateauRangeAfterPeaksEditFieldLabel  matlab.ui.control.Label
        PlateauRangeAfterPeaksEditField  matlab.ui.control.NumericEditField
        HalfLifeTimetoPeakLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NottheLastCutCheckBox
        function NottheLastCutCheckBoxValueChanged(app, event)
            value = app.NottheLastCutCheckBox.Value;
            
            switch value
                case 0
                    app.NextCutIndexEditField.Visible = 'off';
                    app.NextCutIndexEditFieldLabel.Visible = 'off';
                case 1
                    app.NextCutIndexEditField.Visible = 'on';
                    app.NextCutIndexEditFieldLabel.Visible = 'on';
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            root1 = app.DataFolderPathEditField.Value + "\";
            root2 = "ROI\";
            root = root1 + root2;
            filetype = '.csv';
            final = zeros(1,2); %creates an empty matrix to be filled later with values
            cutindex = app.CutIndexEditField.Value;
            nextcutval = app.NottheLastCutCheckBox.Value;
            tomval = app.TOMCheckBox.Value;
            notfirstcutval = app.NottheFirstCutCheckBox.Value;
            if notfirstcutval == 1
                firstcut_index = app.FirstCutIndexEditField.Value;
            else
                firstcut_index = app.CutIndexEditField.Value;
            end
            
            for i = 1:app.NumberofROIsEditField.Value
                if tomval == 1
                    filenum =  "Normalize_TOM_Divide" + num2str(firstcut_index) + "Mean" + num2str(i);
                else
                    filenum =  "Normalize_Divide" + num2str(firstcut_index) + "Mean" + num2str(i);
                end
               
                filepath = strcat(root, filenum, filetype); %this creates/puts together the filename
                wholematrix = csvread(filepath, 1, 0); %reads the files
                x = wholematrix(:,1); %creates an array of x values (times)
                y = wholematrix(:,2); %creates an array of y values (intensities)
                
                if nextcutval == 1
                next_cutindex = app.NextCutIndexEditField.Value;
                else
                next_cutindex = numel(x);
                end
                cuttime = x(cutindex);
                next_cuttime = x(next_cutindex);
                cy = y(cutindex:end); 
                cx = x(cutindex:end);

    %FIND PEAK
    %----------------------------------------
                last_image_time = cx(end,:);
                peakrange_time = cuttime + 60;
                if peakrange_time > last_image_time
                    peakrange_time = last_image_time;
                end
                after_peakrange = find(cx>=peakrange_time); %this takes all the indexes of all the intensities in findrange that are less than or equal to the peak value
                peakrange_onsheet = cx(after_peakrange(1)); %after_peakrange(1) is essentially the index of the first value after the peakrange
                peakrange_index = find(x==peakrange_onsheet,1, 'first'); %this takes the actual index of the peakrange
    
                last_image_index = find(x==last_image_time);
                cy_peak_range = y(cutindex:peakrange_index);%creates a new intensity array that only contains possible range of peak.

                peak = max(cy_peak_range); %finds the value of the intensity of the peak
                peakindex = find(cy==peak,1,'first'); %indexes the value so we can locate the corresponding time in "cx" for this peak
    %----------------------------------------
    
    %FIND PLATEAU
    %----------------------------------------
                peaktime = cx(peakindex); %locates the corresponding time to the peak and saves that value
    %NEED A IF STATEMENT HERE
    
                plateaurange_time = peaktime + app.PlateauRangeAfterPeaksEditField.Value;
                if plateaurange_time > next_cuttime
                    plateaurange_onsheet = next_cuttime;
                else
                    after_plateaurange = find(cx>=plateaurange_time); %this takes all the indexes of all the intensities in findrange that are less than or equal to the plateau value
                    plateaurange_onsheet = cx(after_plateaurange(1)); %after_plateaurange(1) is essentially the index of the first value after the plateaurange
                end
                plateaurange_index = find(cx==plateaurange_onsheet,1, 'first'); %this takes the actual index of the plateaurange
                plateau = min(cy(peakindex:plateaurange_index)); %find the mininum between the peak and the following cut as the plateau
    
                plateau_index = find(cy==plateau,1,'first');%find the index location of the plateau
    
                if plateau_index < peakindex %In case there is a image before peak which has extact same intensity as plateau
                    plateau_index = find(cy==plateau,1,'last');%find the index location of the plateau
                end 
    %----------------------------------------

    %FITTING
    %----------------------------------------
    intensity_array = cy(peakindex:plateau_index); %create an intensity array from the peak to the plateau
    time_array = cx(peakindex:plateau_index); %create an time array from the peak to the plateau
    
    plateau_time = cx(plateau_index);
    
    points_num = numel(intensity_array);%number of intensity array elements
    try
        opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
        opts.Display = 'Off';
        %opts.Normalize = 'on';
        
        [f, gof] = fit(intensity_array,time_array,'exp2',opts); %2 phase exponential decay fitting
   
        half_peak = (peak - plateau)/2 + plateau;
        half_life = f(half_peak) - peaktime;

        r2 = gof.rsquare; %Obtain R-square to examine the goodness of fitting.
    catch %Any error goes to NA
        half_life = "NA";
        r2 = "NA";
    end
    cuttopeak = peaktime - cuttime;
    %----------------------------------------
    final(i,1) = i; %this serves as a label to identify the time with the csv file
    final(i,2) = peaktime;
    final(i,3) = peak;
    final(i,4) = plateau_time; %saves the subtracted time between the peaks to a location in the matrix
    final(i,5) = plateau;
    final(i,6) = half_life;
    final(i,7) = r2;
    final(i,8) = points_num;
    final(i,9) = cuttopeak;
            end
            output = array2table(final,'VariableNames',{'Cell','Peak_Time','Peak_Value','Plateau_Time','Plateau_Value','Half_Life','Rsquare','Num_Points','cut_to_peak'}); %writes the matrix containing all subtracted times to a single csv file containing all the times

            output_filename = "Half-life for the "+cuttime+"s cut";

            finalwrite = strcat(root1,output_filename,filetype); %will write to the location where all the csv files are

            writetable(output,finalwrite);
        end

        % Value changed function: NottheFirstCutCheckBox
        function NottheFirstCutCheckBoxValueChanged(app, event)
            value = app.NottheFirstCutCheckBox.Value;
            switch value
                case 0
                    app.FirstCutIndexEditField.Visible = 'off';
                    app.FirstCutIndexEditFieldLabel.Visible = 'off';
                case 1
                    app.FirstCutIndexEditField.Visible = 'on';
                    app.FirstCutIndexEditFieldLabel.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataFolderPathEditFieldLabel = uilabel(app.UIFigure);
            app.DataFolderPathEditFieldLabel.Text = 'Data Folder Path';
            app.DataFolderPathEditField = uieditfield(app.UIFigure, 'text');
            app.NottheLastCutCheckBox = uicheckbox(app.UIFigure);
            app.NottheLastCutCheckBox.ValueChangedFcn = createCallbackFcn(app, @NottheLastCutCheckBoxValueChanged, true);
            app.NottheLastCutCheckBox.Text = 'Not the Last Cut';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.TOMCheckBox = uicheckbox(app.UIFigure);
            app.TOMCheckBox.Text = 'TOM';
            app.NottheFirstCutCheckBox = uicheckbox(app.UIFigure);
            app.NottheFirstCutCheckBox.ValueChangedFcn = createCallbackFcn(app, @NottheFirstCutCheckBoxValueChanged, true);
            app.NottheFirstCutCheckBox.Text = 'Not the First Cut';
            app.CutIndexEditFieldLabel = uilabel(app.UIFigure);
            app.CutIndexEditFieldLabel.Text = 'Cut Index';
            app.CutIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofROIsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofROIsEditFieldLabel.Text = 'Number of ROIs';
            app.NumberofROIsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NextCutIndexEditFieldLabel = uilabel(app.UIFigure);
            app.NextCutIndexEditFieldLabel.Visible = 'off';
            app.NextCutIndexEditFieldLabel.Text = 'Next Cut Index';
            app.NextCutIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.NextCutIndexEditField.Visible = 'off';
            app.FirstCutIndexEditFieldLabel = uilabel(app.UIFigure);
            app.FirstCutIndexEditFieldLabel.Visible = 'off';
            app.FirstCutIndexEditFieldLabel.Text = 'First Cut Index';
            app.FirstCutIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.FirstCutIndexEditField.Visible = 'off';
            app.PlateauRangeAfterPeaksEditFieldLabel = uilabel(app.UIFigure);
            app.PlateauRangeAfterPeaksEditFieldLabel.Text = 'Plateau Range After Peak (s)';
            app.PlateauRangeAfterPeaksEditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfLifeTimetoPeakLabel = uilabel(app.UIFigure);
            app.HalfLifeTimetoPeakLabel.Text = 'Half-Life & Time to Peak';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HalfLife_and_cuttopeak_2_26_20

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end