classdef popup2_27_20_alreadysubtractBG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EnterButton                   matlab.ui.control.Button
        TotalColumnsNofROIsEditFieldLabel  matlab.ui.control.Label
        TotalColumnsNofROIsEditField  matlab.ui.control.NumericEditField
        GFPFilePathEditFieldLabel     matlab.ui.control.Label
        GFPFilePathEditField          matlab.ui.control.EditField
        ImageFilePathEditFieldLabel   matlab.ui.control.Label
        ImageFilePathEditField        matlab.ui.control.EditField
        ToMFilePathEditFieldLabel     matlab.ui.control.Label
        ToMFilePathEditField          matlab.ui.control.EditField
        ToMCheckBox                   matlab.ui.control.CheckBox
        NormalizationwithoutSubtractBackgroundLabel  matlab.ui.control.Label
        RowNumberCutIndexEditFieldLabel  matlab.ui.control.Label
        RowNumberCutIndexEditField    matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            function fileList = getFiles(dirName)
             dirData = dir(dirName);      %# Get the data for the current directory
             dirIndex = [dirData.isdir];  %# Find the index for directories
             fileList = {dirData(~dirIndex).name}';  %'# Get a list of the files
            end
            
            done = "done";
            
            %normval = app.NormalizationCheckBox.Value;
            tomval = app.ToMCheckBox.Value;
            gfpfilepath = app.GFPFilePathEditField.Value + "\Results.csv";
            tomfilepath = app.ToMFilePathEditField.Value;
%             filename = filepath;
            data = csvread(gfpfilepath,1,2);
            if tomval == 1
                tomdata = csvread(tomfilepath, 1,2);
            end
%             data = rawdata(:,3:end);
            columntot = app.TotalColumnsNofROIsEditField.Value;
            rownum = app.RowNumberCutIndexEditField.Value;
            %command = app.OperationDropDown.Value;
            operatecolumn = columntot;%-1;
            photopath = app.ImageFilePathEditField.Value;
            writeto = app.GFPFilePathEditField.Value + "\ROI";
            
            
            %clear workspace
%             clear all, close all;
            %extracted all file image names from folder
            fileNames = getFiles(photopath);
            %extracted time strings from all files
            time = extractBetween(fileNames,"20","_ms");
            %convert everything to seconds
            hour_sec = 3600 * str2num((char((extractBetween(time, "_","h")))));
            minute_sec = 60 * str2num((char((extractBetween(time, "h","m")))));
            second = str2num((char((extractBetween(time, "m_","s")))));
            totseconds = hour_sec + minute_sec + second;
            
            %plot(totseconds) to check
            
            %subtract seconds from first
            subtractsecond = totseconds - totseconds(1);
            
            %plot(subtractsecond) to check
            
            %write to a csv file
%             fid = fopen(writeto, 'wt');
%             fprintf(fid, '%s\n', 'Time');
%             fprintf(fid, '%d\n', subtractsecond);   %transpose is important!
%             fclose(fid);


               if tomval == 1
                    GFPoperatedata = data(:,1:operatecolumn);
                    GFPMean = GFPoperatedata;% - data(:, columntot);
                    TOMoperatedata = tomdata(:,1:operatecolumn);
                    TOMMean = TOMoperatedata;% - tomdata(:, columntot);
                    Mean = GFPMean ./ TOMMean;
                    row = Mean(rownum,:);
                    Mean = Mean ./ row;
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Normalize_TOM_Divide ',num2str(app.RowNumberCutIndexEditField.Value), meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberCutIndexEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
                    done
%                     sheetname = strcat('Normalize Divide ',app.RowNumberCutIndexEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
               else  % dividing and normalizing
                    operatedata = data(:,1:operatecolumn);
                    Mean = operatedata; %- data(:, columntot);
                    row = Mean(rownum,:);
                    Mean = Mean ./ row;
%                     output = array2table(Mean);
                    for i = 1:operatecolumn
                        a = zeros(size(Mean,1),2);
                        a(:,1) = subtractsecond(:,1);
                        a(:,2) = Mean(:,i);
                        meanheader = strcat('Mean',num2str(i));
                        output = array2table(a,'VariableNames',{'Time',meanheader});
                        finalwrite = strcat(writeto,'/Normalize_Divide ',num2str(app.RowNumberCutIndexEditField.Value), meanheader,".csv");
%                         sheetname = strcat('Normalize Subtract ',app.RowNumberCutIndexEditField.Value, meanheader);
                        writetable(output,finalwrite);
                    end
                    done
%                     sheetname = strcat('Normalize Divide ',app.RowNumberCutIndexEditField.Value);
%                     writetable(output,writeto,'Sheet',sheetname)
               end

        end

        % Value changed function: ToMCheckBox
        function ToMCheckBoxValueChanged(app, event)
            value = app.ToMCheckBox.Value;
            
            switch value
                case 0
                    app.ToMFilePathEditField.Visible = 'off';
                    app.ToMFilePathEditFieldLabel.Visible = 'off';
                case 1
                    app.ToMFilePathEditField.Visible = 'on';
                    app.ToMFilePathEditFieldLabel.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.TotalColumnsNofROIsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalColumnsNofROIsEditFieldLabel.Text = 'Total Columns (N of ROIs)';
            app.TotalColumnsNofROIsEditField = uieditfield(app.UIFigure, 'numeric');
            app.GFPFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.GFPFilePathEditFieldLabel.Text = 'GFP File Path';
            app.GFPFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.ImageFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.ImageFilePathEditFieldLabel.Text = 'Image File Path';
            app.ImageFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.ToMFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.ToMFilePathEditFieldLabel.Visible = 'off';
            app.ToMFilePathEditFieldLabel.Text = 'ToM File Path';
            app.ToMFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.ToMFilePathEditField.Visible = 'off';
            app.ToMCheckBox = uicheckbox(app.UIFigure);
            app.ToMCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToMCheckBoxValueChanged, true);
            app.ToMCheckBox.Text = 'ToM';
            app.NormalizationwithoutSubtractBackgroundLabel = uilabel(app.UIFigure);
            app.NormalizationwithoutSubtractBackgroundLabel.Text = 'Normalization without Subtract Background';
            app.RowNumberCutIndexEditFieldLabel = uilabel(app.UIFigure);
            app.RowNumberCutIndexEditFieldLabel.Text = 'Row Number (Cut Index)';
            app.RowNumberCutIndexEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = popup2_27_20_alreadysubtractBG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end