classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TrimButton        matlab.ui.control.Button
        PickPointsButton  matlab.ui.control.Button
        right             matlab.ui.control.Button
        left              matlab.ui.control.Button
        Label             matlab.ui.control.Label
        LoadFileButton    matlab.ui.control.Button
        ImageSlider       matlab.ui.control.Slider
        ImageLabel        matlab.ui.control.Label
        PlayButton        matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        myFolder    
        filePattern
        tifFiles   
        play
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIAxes,'DataAspectRatio',[2 2 1])
        end

        % Button pushed function: LoadFileButton
        function LoadFIle(app, event)
            try
                app.myFolder = uigetdir('C:');  % get file
                app.filePattern = fullfile(app.myFolder, '*.tif'); % gets all the tif files
                app.tifFiles = dir(app.filePattern);
                
                len = length(app.tifFiles); % takes length and adjusts the slider
                app.ImageSlider.Limits = [1, len];
                
                set(app.ImageSlider, 'value', 1) % sets slider
                baseFileName = app.tifFiles(1).name; % gets specific image
                fullFileName = fullfile(app.myFolder, baseFileName);
                imageArray = imread(fullFileName);
                app.Label.Text = baseFileName; % displays image on axis and displays name
                % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
                % Display image and stretch to fill axes
                I = imshow(imageArray, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
                % Set limits of axes
                app.UIAxes.XLim = [0 I.XData(2)];
                app.UIAxes.YLim = [0 I.YData(2)];
                drawnow;
            catch
                warning("There was an error in selecting your files. Please try again.");
            end
        end

        % Button pushed function: left
        function leftPressed(app, event)
            L = get(gca,{'xlim','ylim'});  % Get axes limits.
            
            x = app.ImageSlider.Value;
            
            if x ~= 1
                app.ImageSlider.Value = x - 1;
            else
                app.ImageSlider.Value = app.ImageSlider.Limits(2);
            end
            
            x = app.ImageSlider.Value;
            
            baseFileName = app.tifFiles(x).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            %app.UIAxes.XLim = [0 I.XData(2)];
            %app.UIAxes.YLim = [0 I.YData(2)];
            
            zoom reset
            set(gca,{'xlim','ylim'},L)
            drawnow;
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            L = get(gca,{'xlim','ylim'});  % Get axes limits.
            
            x = round(app.ImageSlider.Value);
            
            if x ~= app.ImageSlider.Limits(2)
                app.ImageSlider.Value = x + 1;
            else
                app.ImageSlider.Value = 1;
            end
            
            x = app.ImageSlider.Value;
            
            baseFileName = app.tifFiles(x).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            %app.UIAxes.XLim = [0 I.XData(2)];
            %app.UIAxes.YLim = [0 I.YData(2)];
            zoom reset
            set(gca,{'xlim','ylim'},L)
            
            drawnow;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %IMPORTANT nOTE: need to trim before or it'll take too long 
            % ~50 images took around 1~2 minutes
            
            ii=1;
            imageNames = {app.tifFiles.name}';
            %gets the array with the file names
            outputVideo = VideoWriter('movie.avi')
            %creates a video
            open(outputVideo);
            %opens video (makes it able to be edited)
            for ii = 1:length(imageNames)
               img = imread(fullfile(app.myFolder, imageNames{ii}));
               img = im2double(img)
               writeVideo(outputVideo,img)
            end
            %put in every image as a frame
            close(outputVideo)
            %close video (finalizes video)
            
            
            outputMovie = VideoReader(fullfile('movie.avi'));
            ii = 1;
            while hasFrame(outputMovie)
               mov(ii) = im2frame(readFrame(outputMovie));
               ii = ii+1;
            end
            figure 
            imshow(mov(1).cdata, 'Border', 'tight','Parent', app.UIAxes)
            movie(mov,1,20)
            %shows video (set 20fps by default, can change)
            %may want to move the play video part to a separate part to
            %avoid having to create the video again
            
        end

        % Button pushed function: PickPointsButton
        function PickPointsButtonPushed(app, event)
            axis on
            hold on;
            [x1,y1] = ginput(1);  
            plot(app.UIAxes,x1,y1);
            [x2,y2] = ginput(1);
            plot(app.UIAxes,x2,y2);
            
            h = drawpolyline('Position',[x1 y1;x2 y2]);
            distance = sqrt((x2-x1)^2+(y2-y1)^2);
            disp("Distance is " + distance)
        end

        % Button pushed function: TrimButton
        function TrimButtonPushed(app, event)
          
            folderName = inputdlg("Name of New Folder"); %user puts in folder name
            folderLocation = uigetdir('C:'); 
            mkdir folderLocation folderName %creates folder with that name,
            %fixed syntax
            %no idea what fullfile does <-- ~= returns the file location
            
            outputFolder = uigetdir('C:'); % takes in a directory for output
            answer = inputdlg("number of files in output"); % takes number
            trimfiles(app.myFolder, outputFolder, str2double(answer)); % calls function
            
            app.myFolder = outputFolder;
            app.filePattern = fullfile(app.myFolder, '*.tif');
            app.tifFiles = dir(app.filePattern);
            app.play = false;
            
            len = length(app.tifFiles);
            app.ImageSlider.Limits = [0 len-1];
            
            set(app.ImageSlider, 'value', 1)
            baseFileName = app.tifFiles(1).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
        end

        % Value changed function: ImageSlider
        function ImageSliderValueChanged(app, event)
            x = round(app.ImageSlider.Value);
            app.ImageSlider.Value = x;
            
            baseFileName = app.tifFiles(x).name;
            fullFileName = fullfile(app.myFolder, baseFileName);
            imageArray = imread(fullFileName);    
            app.Label.Text = baseFileName;
            % imshow(imageArray, 'parent', app.UIAxes);  % Display image.
            % Display image and stretch to fill axes
            I = imshow(imageArray, 'Parent', app.UIAxes, ...
            'XData', [1 app.UIAxes.Position(3)], ...
            'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
            drawnow; % Force display to update immediately.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ImageSlider = uislider(app.UIFigure);
            app.ImageSlider.Limits = [0 200];
            app.ImageSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSliderValueChanged, true);
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFIle, true);
            app.LoadFileButton.Text = {'Load File'; ''};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.left = uibutton(app.UIFigure, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPressed, true);
            app.left.Text = '<-';
            app.right = uibutton(app.UIFigure, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '->';
            app.PickPointsButton = uibutton(app.UIFigure, 'push');
            app.PickPointsButton.ButtonPushedFcn = createCallbackFcn(app, @PickPointsButtonPushed, true);
            app.PickPointsButton.Text = 'Pick Points';
            app.TrimButton = uibutton(app.UIFigure, 'push');
            app.TrimButton.ButtonPushedFcn = createCallbackFcn(app, @TrimButtonPushed, true);
            app.TrimButton.Text = 'Trim';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end