classdef TetherProjectApp2019v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LoadImagesROITab              matlab.ui.container.Tab
        SkipfromEndEditField          matlab.ui.control.EditField
        SkipfromEndEditFieldLabel     matlab.ui.control.Label
        SkipfromStartEditField        matlab.ui.control.EditField
        SkipfromStartEditFieldLabel   matlab.ui.control.Label
        ImageIntervalEditField        matlab.ui.control.EditField
        ImageIntervalEditFieldLabel   matlab.ui.control.Label
        TrimFilesButton               matlab.ui.control.Button
        MaximumBrightnessSlider       matlab.ui.control.Slider
        MaximumBrightnessSliderLabel  matlab.ui.control.Label
        PlayButton                    matlab.ui.control.Button
        MinimumBrightnessSlider       matlab.ui.control.Slider
        MinimumBrightnessSliderLabel  matlab.ui.control.Label
        Yearlast2EditField            matlab.ui.control.EditField
        Yearlast2EditFieldLabel       matlab.ui.control.Label
        GetDistancesButton            matlab.ui.control.Button
        right                         matlab.ui.control.Button
        left                          matlab.ui.control.Button
        LoadFileButton                matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        ImageSlider                   matlab.ui.control.Slider
        ImageLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        myFolder    
        filePattern
        tifFiles   
        play
        f
        a
        
        numImages
        
        brightness % using this cause idk times needed to call obj
        brightnessMin
        brightnessMax
        playTimer
        I
        
        shownPoints
        positions
        results
    end
    
    methods (Access = private)
        
        function displayImage(app, index, new)
            set(app.ImageSlider, 'value', round(index));
            
            if not(new)
                delpoints(app);
                xZoom = app.a.XLim;
                yZoom = app.a.YLim;
            end
            
            file = app.tifFiles(round(index)); % why is there a round y?
            filePath = fullfile(file.folder, file.name);
            % baseFileName = file.name; 
            % fullFileName = fullfile(app.myFolder + "\GFP", baseFileName);

            imageArray = imread(filePath) + app.getBrightness();
            app.Label.Text = file.name;
            app.ImageLabel.Text = round(index)+ "/" +app.numImages + " : " + app.results(round(index), 1) + "s";
            
            global imageshown
            imageshown = imageArray;
            try
                imshow(imadjust(imageArray), 'parent', app.a, 'DisplayRange',[app.brightnessMin app.brightnessMax]);  % Display image.
            catch
                warning("The HIGH must be greater than the LOW");
            end
            % Display image and stretch to fill axes
            % app.I = imshow(imadjust(imageArray), app.a);
            
            % app.showRoi();
            showPoint(app);
            if not(new)
                app.a.XLim = xZoom;
                app.a.YLim = yZoom;
            end
            figure(app.UIFigure);
            
            % Set limits of axes
            % app.UIAxes.XLim = [0 app.I.XData(2)];
            % app.UIAxes.YLim = [0 app.I.YData(2)];
            drawnow;
            
        end
        
        function brightness = getBrightness(app)
            brightness = app.brightness * 600;
        end
        
        
        function results = dist(~, point1, point2)
            d1 = point2(1)-point1(1);
            d2 = point2(2)-point1(2);
            results = sqrt(d1.^2 + d2.^2);
        end
        
        function showPoint(app)
            i = app.ImageSlider.Value;
            app.shownPoints{1} = images.roi.Point(app.a, 'Position', app.positions(i, 1:2), 'Label', "Cut Pole");
            addlistener(app.shownPoints{1}, 'MovingROI', @(src, ~) app.movingROICallback(src));
            
            app.shownPoints{2} = images.roi.Point(app.a, 'Position', app.positions(i, 3:4), 'Label', "Opposite Pole");
            addlistener(app.shownPoints{2}, 'MovingROI', @(src, ~) app.movingROICallback(src));
            
            app.shownPoints{3} = images.roi.Point(app.a, 'Position', app.positions(i, 5:6), 'Label', "Fragment Tip");
            addlistener(app.shownPoints{3}, 'MovingROI', @(src, ~) app.movingROICallback(src));
            
            app.shownPoints{4} = images.roi.Point(app.a, 'Position', app.positions(i, 7:8), 'Label', "Sister Tip");
            addlistener(app.shownPoints{4}, 'MovingROI', @(src, ~) app.movingROICallback(src));
            
            app.shownPoints{5} = images.roi.Point(app.a, 'Position', app.positions(i, 9:10), 'Label', "Fixed Point");
            addlistener(app.shownPoints{5}, 'MovingROI', @(src, ~) app.movingROICallback(src));
            disp("Cut Poles is at: " + app.shownPoints{1}.Position)
            disp("Opposite Poles is at: " + app.shownPoints{2}.Position)
            disp("Fragment Tip is at: " + app.shownPoints{3}.Position)
            disp("Sister Tip is at: " + app.shownPoints{4}.Position)
            disp("Fixed Point is at: " + app.shownPoints{5}.Position)
        end
        
        
        function movingROICallback(app, src)
            x = app.ImageSlider.Value;
            rx = src.Label;
            disp(rx);
            switch rx
                case 'Cut Pole'
                app.positions(x, 1) = src.Position(1);
                app.positions(x, 2) = src.Position(2);
                case 'Opposite Pole'
                app.positions(x, 3) = src.Position(1);
                app.positions(x, 4) = src.Position(2);
                case 'Fragment Tip'
                app.positions(x, 5) = src.Position(1);
                app.positions(x, 6) = src.Position(2);
                case 'Sister Tip'
                app.positions(x, 7) = src.Position(1);
                app.positions(x, 8) = src.Position(2);
                case 'Fixed Point'
                app.positions(x, 9) = src.Position(1);
                app.positions(x, 10) = p.Position(2);
            
            end
            
            % figure(app.UIFigure);
        end
        
        function setup(app)
            
            function fileList = getFiles(dirName)
                dirData = dir(dirName);      %# Get the data for the current directory
                dirIndex = [dirData.isdir];  %# Find the index for directories
                fileList = {dirData(~dirIndex).name}';  %'# Get a list of the files
            end
            % all of these are arrays of strings/ints
            fileNames = getFiles(app.myFolder);
            time = extractBetween(fileNames,"_"+app.Yearlast2EditField.Value,"_ms");%extracted time strings from all files
            %convert everything to seconds
            hour_sec = 3600 * str2num((char((extractBetween(time, "_","h")))));
            minute_sec = 60 * str2num((char((extractBetween(time, "h","m")))));
            second = str2num((char((extractBetween(time, "m_","s")))));
            totseconds = hour_sec + minute_sec + second;
            
            % plot(totseconds) % to check
            
            %subtract seconds from first
            subtractsecond = totseconds - totseconds(1);
            
            app.results = zeros(app.numImages, 7);
            app.results(:,1) = subtractsecond(:,1);
        end
        
        function dispNext(app)
            if not(app.numImages)
                return;
            end
            
            y = min(app.ImageSlider.Value+1, app.numImages);
            displayImage(app, y, 0);
        end


        function drawPoint(app, point)
            y = app.ImageSlider.Value;
            switch point
                case '1'
                    p = images.roi.Point(app.a, 'Position', app.positions(y, 1:2), 'Label', "Cut Pole");
                case '2'
                    p = images.roi.Point(app.a, 'Position', app.positions(y, 3:4), 'Label', "Opposite Pole");
                case '3'
                    p = images.roi.Point(app.a, 'Position', app.positions(y, 5:6), 'Label', "Fragment Tip");
                case '4'
                    p = images.roi.Point(app.a, 'Position', app.positions(y, 7:8), 'Label', "Sister Tip");
                case '5'
                    p = images.roi.Point(app.a, 'Position', app.positions(y, 9:10), 'Label', "Fixed Point");
            end
            addlistener(p, 'MovingROI', @(src, ~) app.movingROICallback(src));
        end
        
        function showGraph(app)
            
            Array=csvread(outputFolder);
            % get time in here as the x axis
            photopath = app.myFolder +"\GFP";
            fileNames = getFiles(photopath);
        
            time = extractBetween(fileNames,"_19","_ms");%extracted time strings from all files
            %convert everything to seconds
            hour_sec = 3600 * str2num((char((extractBetween(time, "_","h")))));
            minute_sec = 60 * str2num((char((extractBetween(time, "h","m")))));
            second = str2num((char((extractBetween(time, "m_","s")))));
            totseconds = hour_sec + minute_sec + second;
            
            %subtract seconds from first
            subtractsecond = totseconds - totseconds(1);
            
            %creates a new window
            graph_window = figure;
            ax = axes('Parent',graph_window);
            ax.Title.String = "The Average Intensity of Calcium Cell Over Time (seconds)";
            xlabel(ax,'Time (seconds)','FontSize',12)
            ylabel(ax,'Average Intensity of Calcium Cell','FontSize',12)
            hold(ax,'on')
            % remove the -1 part if you want to see the background also
            % graphed
            legend('location', 'best');
            for j = 1:(size(app.means,2)-1)
                %gets the data from the respective column per iteration
                col{j} = Array(:, j); 
                %plots the graph using the color on the cell drawing
                label = ['Cell# ',num2str(j)];
                plot(ax, subtractsecond, col{j}, colors{j}, 'DisplayName', label)
                %saves graph for next iteration
                hold on
            end
            %stops saving the graphs
            hold off
            
        end
        
        function delpoints(app)
            delete(app.shownPoints{1})
            delete(app.shownPoints{2})
            delete(app.shownPoints{3})
            delete(app.shownPoints{4})
            delete(app.shownPoints{5})
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set(app.UIAxes,'DataAspectRatio',[2 2 1])
            
            app.play = false;
            
            app.numImages = 0;
            app.brightness = 0; 
            app.brightnessMin = 0;
            app.brightnessMax = 65535;
            app.shownPoints = {};
            app.playTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.1, 'TimerFcn', @(~, ~) app.dispNext());
        end

        % Button pushed function: LoadFileButton
        function LoadFIle(app, event)
            try
                app.myFolder = uigetdir('C:');  % get file
                app.filePattern = fullfile(app.myFolder, "*.tif");
                % if app.TOMCheckBox.Value == 1
                %     app.filePattern = fullfile(app.myFolder + "\ToM", '*.tif'); % gets all the tif files
                % else
                %     app.filePattern = fullfile(app.myFolder + "\GFP", '*.tif'); % gets all the tif files
                % end        
                
                app.tifFiles = dir(app.filePattern);
                
                % set(app.a,'DataAspectRatio',[2 2 1]);
                
                app.numImages = length(app.tifFiles); % takes length and adjusts the slider
                app.ImageSlider.Limits = [1 app.numImages];
                
                app.positions = zeros(app.numImages, 10);
                app.setup();
                
                if not(app.numImages)
                    return;
                end
                
                app.f = figure('Name',"Image");
                app.a = axes();
                
                set(app.ImageSlider, 'value', 1) % sets slider
                displayImage(app, 1, 1);
                
                
                app.ImageSlider.MajorTicks = (0:round(app.numImages/10):app.numImages);
                app.ImageSlider.MajorTickLabels = strsplit(num2str(0:round(app.numImages/10):app.numImages));
                app.ImageSlider.MinorTicks = (1:max(round(app.numImages/100), 1):app.numImages);
                
                figure(app.UIFigure); % focus window again
            catch
                 warning("There was an error in selecting your files. Please try again.");
            end
        end

        % Button pushed function: TrimFilesButton
        function TrimFilesButtonPushed(app, event)
            % folderName = inputdlg("Name of Folder"); %user puts in folder name
            % folderLocation = app.myFolder;
            % mkdir(fullfile(folderLocation,folderName(1))); %creates folder with that name, 
            % %no idea what fullfile does
            axis on
            hold on;
            outputFolder = uigetdir();
            answer = app.ImageIntervalEditField.Value; % takes number
            startVal = app.SkipfromStartEditField.Value;
            endVal = app.SkipfromEndEditField.Value;
            trimfiles(str2double(startVal), str2double(endVal), app.myFolder, outputFolder, str2double(answer)); % calls function
            
            app.myFolder = outputFolder;
            app.filePattern = fullfile(app.myFolder, '*.tif');
            app.tifFiles = dir(app.filePattern);
            
            close(app.f);
            app.f = figure('Name',"Image");
            app.a = axes();
                
            app.numImages = length(app.tifFiles); % takes length and adjusts the slider
            app.ImageSlider.Limits = [1 app.numImages];
                
            app.positions = zeros(app.numImages, 10);
            app.setup();
            
            set(app.ImageSlider, 'value', 1) % sets slider
            displayImage(app, 1, 1);
            
            app.ImageSlider.MajorTicks = (0:round(app.numImages/10):app.numImages);
            app.ImageSlider.MajorTickLabels = strsplit(num2str(0:round(app.numImages/10):app.numImages));
            app.ImageSlider.MinorTicks = (1:max(round(app.numImages/100), 1):app.numImages);
        end

        % Value changing function: ImageSlider
        function ImageSliderValueChanging(app, event)
            axis on
            hold on;
            if not(app.numImages)
                return;
            end
            
            y = event.Value;
            displayImage(app, y, 0);
        end

        % Button pushed function: left
        function leftPressed(app, event)
            axis on
            hold on;
            if not(app.numImages)
                return;
            end
            
            y = max(app.ImageSlider.Value-1, 1);
            displayImage(app, y, 0);
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)
            axis on
            hold on;
            if not(app.numImages)
                return;
            end
            
            y = min(app.ImageSlider.Value+1, app.numImages);
            displayImage(app, y, 0);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            axis on
            hold on;
            app.play = not(app.play);
            if app.play
                app.PlayButton.Text = 'Stop';
                start(app.playTimer);
                
            else
                app.PlayButton.Text = 'Play';
                stop(app.playTimer);
                
            end
            
            
        end

        % Value changing function: MinimumBrightnessSlider
        function MinimumBrightnessSliderValueChanging(app, event)
            axis on
            hold on;
            app.brightnessMin = event.Value;
            app.displayImage(app.ImageSlider.Value, 0);
        end

        % Value changing function: MaximumBrightnessSlider
        function MaximumBrightnessSliderValueChanging(app, event)
            axis on
            hold on;
            app.brightnessMax = event.Value;
            app.displayImage(app.ImageSlider.Value, 0);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            axis on
            hold on;
            key = event.Key;
            x = round(app.ImageSlider.Value);
            switch key
                case '1'
                    app.Label.Text = "Select Cut Pole";
                    figure(app.f);
                    % Draw point
                    shownPoints{1} = drawpoint(app.a, 'Label', 'Cut Pole');
                    % Position is a part of a point.  Its a 1x2 array
                    app.positions(x, 1) = shownPoints{1}.Position(1);
                    app.positions(x, 2) = shownPoints{1}.Position(2);
                    
                    
                    figure(app.UIFigure);
                    app.Label.Text = app.tifFiles(x).name;
                case '2'
                    app.Label.Text = "Select Opposite Pole";
                    figure(app.f);
                    app.shownPoints{2} = drawpoint(app.a, 'Label', 'Opposite Pole');
                    app.positions(x, 3) = app.shownPoints{2}.Position(1);
                    app.positions(x, 4) = app.shownPoints{2}.Position(2);
                    figure(app.UIFigure);
                    app.Label.Text = app.tifFiles(x).name;
                case '3'
                    app.Label.Text = "Select Fragment Tip";
                    figure(app.f);
                    app.shownPoints{3} = drawpoint(app.a, 'Label', 'Fragment Tip');
                    app.positions(x, 5) = app.shownPoints{3}.Position(1);
                    app.positions(x, 6) = app.shownPoints{3}.Position(2);
                    figure(app.UIFigure);
                    app.Label.Text = app.tifFiles(x).name;
                case '4'
                    app.Label.Text = "Select Sister Tip";
                    figure(app.f);
                    app.shownPoints{4} = drawpoint(app.a, 'Label', 'Sister Tip');
                    app.positions(x, 7) = app.shownPoints{4}.Position(1);
                    app.positions(x, 8) = app.shownPoints{4}.Position(2);
                    figure(app.UIFigure);
                    app.Label.Text = app.tifFiles(x).name;
                case '5'
                    app.Label.Text = "Select Fixed Point";
                    figure(app.f);
                    app.shownPoints{5} = drawpoint(app.a, 'Label', 'Fixed Point');
                    app.positions(x, 9) = app.shownPoints{5}.Position(1);
                    app.positions(x, 10) = app.shownPoints{5}.Position(2);
                    figure(app.UIFigure);
                    app.Label.Text = app.tifFiles(x).name;
                case 'q'
                    if not(app.numImages)
                        return;
                    end
                    y = max(app.ImageSlider.Value-1, 1);
                    displayImage(app, y, 0);
                case 'e'
                    if not(app.numImages)
                        return;
                    end
                    y = min(app.ImageSlider.Value+1, app.numImages);
                    displayImage(app, y, 0);
            end
            
            try
                 % addlistener(p, 'MovingROI', @(src, ~) app.movingROICallback(src));
                 displayImage(app, app.ImageSlider.Value, 0);
            catch 
            end
        end

        % Button pushed function: GetDistancesButton
        function GetDistancesButtonPushed(app, event)
            app.Label.Text = "Working";
            
            mkdir(app.myFolder+"\data");
            % Get all of the distances
            for i = 1:app.numImages
                distance = dist(app, app.positions(i, 1:2), app.positions(i, 9:10));
                app.results(i, 2) = distance;
                distance = dist(app, app.positions(i, 3:4), app.positions(i, 9:10));
                app.results(i, 3) = distance;
                distance = dist(app, app.positions(i, 5:6), app.positions(i, 9:10));
                app.results(i, 4) = distance;
                distance = dist(app, app.positions(i, 7:8), app.positions(i, 9:10));
                app.results(i, 5) = distance;
                distance = dist(app, app.positions(i, 1:2), app.positions(i, 3:4));
                app.results(i, 6) = distance;
                distance = dist(app, app.positions(i, 5:6), app.positions(i, 7:8));
                app.results(i, 7) = distance;
            end
            writematrix(app.results, app.myFolder+"\data\Results.csv");
            % Create new Window
            gfig = figure('Name',"From Fixed Point");
            graph = axes();
            % Separate columns
            x = app.results(:, 1);
            y1 = app.results(:,2);
            y2 = app.results(:,3);
            y3 = app.results(:,4);
            y4 = app.results(:,5);
            
            % plot all of the graphs
            plot(graph, x, y1, '- .');
            hold on;
            plot(graph, x, y2, '- .');
            plot(graph, x, y3, '- .');
            plot(graph, x, y4, '- .');
            % Label and Legend
            xlabel(graph, 'Time(s)');
            ylabel(graph, 'Distance(px)');
            legend(graph, 'Cut Pole', 'Opposite Pole', 'Fragment Tip', 'Sister Tip')
            hold off;
            saveas(gfig, app.myFolder+"\data\From_Fixed_Point")
            
            gfig2 = figure("Name", "Between Points")
            graph1 = axes()
            x = app.results(:, 1);
            y1 = app.results(:,6);
            y2 = app.results(:,7);
            plot(graph1, x, y1, '- .');
            hold on;
            plot(graph1, x, y2, '- .');
            xlabel(graph1, 'Time(s)');
            ylabel(graph1, 'Distance(px)');
            legend(graph1, 'Between Poles', 'Between Tips');
            hold off;
            saveas(gfig2, app.myFolder+"\data\Between_Poles_Tips")
            
            app.Label.Text = "Done";
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadImagesROITab = uitab(app.TabGroup);
            app.LoadImagesROITab.Title = 'Load Images + ROI';
            app.ImageLabel = uilabel(app.LoadImagesROITab);
            app.ImageLabel.Text = 'Image';
            app.ImageSlider = uislider(app.LoadImagesROITab);
            app.ImageSlider.Limits = [0 200];
            app.ImageSlider.MajorTicks = [0 8 16 24 32 40 48 56 64 72 80 88 96 104 112 120 128 136 144 152 160 168 176 184 192 200];
            app.ImageSlider.MajorTickLabels = {'0', '8', '16', '24', '32', '40', '48', '56', '64', '72', '80', '88', '96', '104', '112', '120', '128', '136', '144', '152', '160', '168', '176', '184', '192', '200'};
            app.ImageSlider.ValueChangingFcn = createCallbackFcn(app, @ImageSliderValueChanging, true);
            app.ImageSlider.MinorTicks = [0 1.6 3.2 4.8 6.4 8 9.6 11.2 12.8 14.4 16 17.6 19.2 20.8 22.4 24 25.6 27.2 28.8 30.4 32 33.6 35.2 36.8 38.4 40 41.6 43.2 44.8 46.4 48 49.6 51.2 52.8 54.4 56 57.6 59.2 60.8 62.4 64 65.6 67.2 68.8 70.4 72 73.6 75.2 76.8 78.4 80 81.6 83.2 84.8 86.4 88 89.6 91.2 92.8 94.4 96 97.6 99.2 100.8 102.4 104 105.6 107.2 108.8 110.4 112 113.6 115.2 116.8 118.4 120 121.6 123.2 124.8 126.4 128 129.6 131.2 132.8 134.4 136 137.6 139.2 140.8 142.4 144 145.6 147.2 148.8 150.4 152 153.6 155.2 156.8 158.4 160 161.6 163.2 164.8 166.4 168 169.6 171.2 172.8 174.4 176 177.6 179.2 180.8 182.4 184 185.6 187.2 188.8 190.4 192 193.6 195.2 196.8 198.4 200];
            app.ImageSlider.Value = 1;
            app.Label = uilabel(app.LoadImagesROITab);
            app.Label.Text = '';
            app.LoadFileButton = uibutton(app.LoadImagesROITab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFIle, true);
            app.LoadFileButton.Text = {'Load File'; ''};
            app.left = uibutton(app.LoadImagesROITab, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftPressed, true);
            app.left.Text = '<';
            app.right = uibutton(app.LoadImagesROITab, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Text = '>';
            app.GetDistancesButton = uibutton(app.LoadImagesROITab, 'push');
            app.GetDistancesButton.ButtonPushedFcn = createCallbackFcn(app, @GetDistancesButtonPushed, true);
            app.GetDistancesButton.Text = 'Get Distances';
            app.Yearlast2EditFieldLabel = uilabel(app.LoadImagesROITab);
            app.Yearlast2EditFieldLabel.Text = {'Year (last 2)'; ''};
            app.Yearlast2EditField = uieditfield(app.LoadImagesROITab, 'text');
            app.Yearlast2EditField.Value = '20';
            app.MinimumBrightnessSliderLabel = uilabel(app.LoadImagesROITab);
            app.MinimumBrightnessSliderLabel.Text = 'Minimum Brightness Slider';
            app.MinimumBrightnessSlider = uislider(app.LoadImagesROITab);
            app.MinimumBrightnessSlider.Limits = [0 65535];
            app.MinimumBrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @MinimumBrightnessSliderValueChanging, true);
            app.PlayButton = uibutton(app.LoadImagesROITab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.MaximumBrightnessSliderLabel = uilabel(app.LoadImagesROITab);
            app.MaximumBrightnessSliderLabel.Text = 'Maximum Brightness Slider';
            app.MaximumBrightnessSlider = uislider(app.LoadImagesROITab);
            app.MaximumBrightnessSlider.Limits = [0 65535];
            app.MaximumBrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @MaximumBrightnessSliderValueChanging, true);
            app.MaximumBrightnessSlider.Value = 65525;
            app.TrimFilesButton = uibutton(app.LoadImagesROITab, 'push');
            app.TrimFilesButton.ButtonPushedFcn = createCallbackFcn(app, @TrimFilesButtonPushed, true);
            app.TrimFilesButton.Text = 'Trim Files';
            app.ImageIntervalEditFieldLabel = uilabel(app.LoadImagesROITab);
            app.ImageIntervalEditFieldLabel.Text = 'Image Interval';
            app.ImageIntervalEditField = uieditfield(app.LoadImagesROITab, 'text');
            app.ImageIntervalEditField.Value = '100';
            app.SkipfromStartEditFieldLabel = uilabel(app.LoadImagesROITab);
            app.SkipfromStartEditFieldLabel.Text = 'Skip from Start';
            app.SkipfromStartEditField = uieditfield(app.LoadImagesROITab, 'text');
            app.SkipfromStartEditField.Value = '0';
            app.SkipfromEndEditFieldLabel = uilabel(app.LoadImagesROITab);
            app.SkipfromEndEditFieldLabel.Text = 'Skip from End';
            app.SkipfromEndEditField = uieditfield(app.LoadImagesROITab, 'text');
            app.SkipfromEndEditField.Value = '100';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TetherProjectApp2019v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end